classdef app1_prueba_axes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        TextAreaLabel                   matlab.ui.control.Label
        DatossimuladosPanel             matlab.ui.container.Panel
        Longsim                         matlab.ui.control.NumericEditField
        LongitufftEditFieldLabel        matlab.ui.control.Label
        Diamsim                         matlab.ui.control.NumericEditField
        DiametroftEditFieldLabel        matlab.ui.control.Label
        Pns                             matlab.ui.control.NumericEditField
        PnetakWEditFieldLabel_2         matlab.ui.control.Label
        Ees                             matlab.ui.control.NumericEditField
        EeskWhtnEditFieldLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        Ayuda                           matlab.ui.control.Button
        Calcular                        matlab.ui.control.Button
        DatosOperacionalesPanel         matlab.ui.container.Panel
        Eeo                             matlab.ui.control.NumericEditField
        EespkWhtnEditFieldLabel         matlab.ui.control.Label
        Pto                             matlab.ui.control.NumericEditField
        PtotalkWEditFieldLabel          matlab.ui.control.Label
        Pno                             matlab.ui.control.NumericEditField
        PnetakWEditFieldLabel           matlab.ui.control.Label
        Wio                             matlab.ui.control.NumericEditField
        WiokWhtnMEditFieldLabel         matlab.ui.control.Label
        DescargaPanel                   matlab.ui.container.Panel
        Dpulpa                          matlab.ui.control.NumericEditField
        Dnspultnm3EditFieldLabel        matlab.ui.control.Label
        P80                             matlab.ui.control.NumericEditField
        P80umEditFieldLabel             matlab.ui.control.Label
        CondicionesdeOperacinPanel      matlab.ui.container.Panel
        Psol                            matlab.ui.control.NumericEditField
        SolidosEditFieldLabel_2         matlab.ui.control.Label
        Pp                              matlab.ui.control.NumericEditField
        PerdsEditFieldLabel             matlab.ui.control.Label
        Db                              matlab.ui.control.NumericEditField
        DBtnm3EditFieldLabel            matlab.ui.control.Label
        Adl                             matlab.ui.control.NumericEditField
        AnguloEditFieldLabel            matlab.ui.control.Label
        Pei                             matlab.ui.control.NumericEditField
        IntersEditFieldLabel            matlab.ui.control.Label
        Pb                              matlab.ui.control.NumericEditField
        BolasEditFieldLabel             matlab.ui.control.Label
        Pc                              matlab.ui.control.NumericEditField
        CargaEditFieldLabel             matlab.ui.control.Label
        Pvc                             matlab.ui.control.NumericEditField
        VcEditFieldLabel                matlab.ui.control.Label
        AlimentoPanel                   matlab.ui.container.Panel
        Agua                            matlab.ui.control.NumericEditField
        Aguam3hEditFieldLabel           matlab.ui.control.Label
        F80                             matlab.ui.control.NumericEditField
        F80umLabel                      matlab.ui.control.Label
        Alimento                        matlab.ui.control.NumericEditField
        MineraltnhEditFieldLabel        matlab.ui.control.Label
        CaracteristicasdelmineralPanel  matlab.ui.container.Panel
        Wi                              matlab.ui.control.NumericEditField
        WikWhtMEditFieldLabel           matlab.ui.control.Label
        Dmin                            matlab.ui.control.NumericEditField
        Dnstnm3EditFieldLabel           matlab.ui.control.Label
        DimensionamientoMolinomPanel    matlab.ui.container.Panel
        LD                              matlab.ui.control.NumericEditField
        LDEditFieldLabel                matlab.ui.control.Label
        L                               matlab.ui.control.NumericEditField
        LongitudEditFieldLabel          matlab.ui.control.Label
        D                               matlab.ui.control.NumericEditField
        DiametroEditFieldLabel          matlab.ui.control.Label
        DimensionamientodeunMolinodebolasLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button pushed function: Calcular
        function CalcularPushed(app, event)
           carga = load('parametros_molino.mat');
            app.D.Value = carga.D;
            app.LD.Value = carga.L_D;
            app.Wi.Value=carga.Wi;
            app.Dmin.Value=carga.Dns;
            app.Alimento.Value=carga.Mineral;
            app.F80.Value=carga.F80;
            app.Pb.Value=carga.Bolas;
            app.Pei.Value=carga.Interes;
            app.Adl.Value=carga.Angulo;
            app.Db.Value=carga.Dbolas;
            app.Psol.Value=carga.Solidos;
            app.Pp.Value=carga.Perdidas;
            app.P80.Value=carga.P80;
            app.Pvc.Value=carga.Vc;
            app.Pc.Value=carga.Carga;
                        
            % Calculo de la energia especifica kW/ton

            windex = app.Wi.Value;
            ff80 = app.F80.Value;
            pp80=app.P80.Value;
            enesp=10*windex*(1/sqrt(pp80)-1/sqrt(ff80));
            app.Ees.Value= enesp;

            % Potencia neta requerida kWh

            tonal = app.Alimento.Value;
            pote_net_sim = enesp*tonal;
            app.Pns.Value = pote_net_sim;

            % Volumen del molino en m^3

            diamreal = app.D.Value; % Diametro en ft
            longreal = app.LD.Value * diamreal ; % longitud en ft
            app.L.Value = longreal;
            Volmol = pi*(diamreal*0.305)^2*(longreal*0.305)/4; % Volumen del molino 

            % Calculo de la densidad de pulpa(tn/m³)

            porsol = app.Psol.Value;
            densmin = app.Dmin.Value; 
            denspulpa = 100/(porsol/densmin+100-porsol);
            app.Dpulpa.Value = denspulpa;

            % Calculo del volumen de carga (m^3)

            porcarga = app.Pc.Value;
            volcarga = (porcarga/100)*Volmol; % Volumen de caga en m^3

            % Peso de carga de bolas en el molino

            porbolas = app.Pb.Value;
            densbola = app.Db.Value;
            pesocarbol = 0.6*densbola*(porbolas/100)*Volmol;

            % Peso de pulpa dentro de los espacios intesticiales de la
            % carga de bolas 

            porespint = app.Pei.Value;
            pesoespint = 0.4*denspulpa*(porespint/100)*(porbolas/100)*Volmol;

            % Peso de pulpa sobre el nivel de las bolas

            pesosobbol = denspulpa*(porcarga/100-porbolas/100)*Volmol;

            % Calculo de la densidad aparente(ton/m^3)

            densaparente = (pesocarbol+pesoespint+pesosobbol)/volcarga;

            % Calculo del diametro del molino necesario
            longdiam = app.LD.Value;
            porvelcrit = app.Pvc.Value;
            angulo = app.Adl.Value;
            diametro_simulado = (pote_net_sim/(0.238*longdiam *(porvelcrit/100)*(densaparente)*(porcarga/100-1.065*(porcarga/100)^2)*sin(angulo * pi / 180)))^(1 / 3.5);
            app.Diamsim.Value = diametro_simulado;
            app.Longsim.Value = diametro_simulado*longdiam;

            % Calculo de agua necesaria(m³/h)

            aguaal = tonal*(1-porsol/100)*100/porsol;
            app.Agua.Value = aguaal;
            % Mostrar solo la densidad aparente en el TextArea
            output = sprintf("Densidad aparente: %.3f ton/m³", pote_net_sim);
            app.TextArea.Value = output;

            % Calculo de la potencia neta en operacion(kW)
            pot_ne_top = 0.238 * diamreal^3.5 * longdiam * (porvelcrit/100) * densaparente *sin(angulo * pi/180)*(porcarga/100 - 1.065 * (porcarga/100)^2);
            app.Pno.Value = pot_ne_top;
    
            % Calculo de potencia total en operación (considerando pérdidas) (kW)

            porper = app.Pp.Value;
            pottotop = pot_ne_top/(1-porper/100);
            app.Pto.Value = pottotop;

            % Energia especifica en operación(kWh/ton)

            enespop = pot_ne_top/(tonal);
            app.Eeo.Value = enespop;

            % Calculo del work index operacional(kWh/ton)
            windexop = enespop /(10*(1/sqrt(pp80)-1/sqrt(ff80)));
            app.Wio.Value=windexop;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'diagrama-molino-de-bolas.gif');
            app.DimensionamientodeunMolinodebolasLabel = uilabel(app.UIFigure);
            app.DimensionamientodeunMolinodebolasLabel.Text = 'Dimensionamiento de un Molino de bolas';
            app.DimensionamientoMolinomPanel = uipanel(app.UIFigure);
            app.DimensionamientoMolinomPanel.Title = '  Dimensionamiento Molino (m)';
            app.DiametroEditFieldLabel = uilabel(app.DimensionamientoMolinomPanel);
            app.DiametroEditFieldLabel.Text = 'Diametro';
            app.D = uieditfield(app.DimensionamientoMolinomPanel, 'numeric');
            app.LongitudEditFieldLabel = uilabel(app.DimensionamientoMolinomPanel);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.L = uieditfield(app.DimensionamientoMolinomPanel, 'numeric');
            app.LDEditFieldLabel = uilabel(app.DimensionamientoMolinomPanel);
            app.LDEditFieldLabel.Text = 'L/D';
            app.LD = uieditfield(app.DimensionamientoMolinomPanel, 'numeric');
            app.CaracteristicasdelmineralPanel = uipanel(app.UIFigure);
            app.CaracteristicasdelmineralPanel.Title = '     Caracteristicas del mineral';
            app.Dnstnm3EditFieldLabel = uilabel(app.CaracteristicasdelmineralPanel);
            app.Dnstnm3EditFieldLabel.Text = 'Dns (tn/m^3)';
            app.Dmin = uieditfield(app.CaracteristicasdelmineralPanel, 'numeric');
            app.WikWhtMEditFieldLabel = uilabel(app.CaracteristicasdelmineralPanel);
            app.WikWhtMEditFieldLabel.Text = 'Wi (kWh/tM)';
            app.Wi = uieditfield(app.CaracteristicasdelmineralPanel, 'numeric');
            app.AlimentoPanel = uipanel(app.UIFigure);
            app.AlimentoPanel.Title = '                          Alimento';
            app.MineraltnhEditFieldLabel = uilabel(app.AlimentoPanel);
            app.MineraltnhEditFieldLabel.Text = 'Mineral (tn/h)';
            app.Alimento = uieditfield(app.AlimentoPanel, 'numeric');
            app.F80umLabel = uilabel(app.AlimentoPanel);
            app.F80umLabel.Text = 'F80 (um)';
            app.F80 = uieditfield(app.AlimentoPanel, 'numeric');
            app.Aguam3hEditFieldLabel = uilabel(app.AlimentoPanel);
            app.Aguam3hEditFieldLabel.Text = 'Agua (m^3/h)';
            app.Agua = uieditfield(app.AlimentoPanel, 'numeric');
            app.CondicionesdeOperacinPanel = uipanel(app.UIFigure);
            app.CondicionesdeOperacinPanel.Title = '                                                                                         Condiciones de Operación';
            app.VcEditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.VcEditFieldLabel.Text = '% Vc';
            app.Pvc = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.CargaEditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.CargaEditFieldLabel.Text = '% Carga';
            app.Pc = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.BolasEditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.BolasEditFieldLabel.Text = '% Bolas';
            app.Pb = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.IntersEditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.IntersEditFieldLabel.Text = '% Inters';
            app.Pei = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.AnguloEditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.Adl = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.DBtnm3EditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.DBtnm3EditFieldLabel.Text = 'DB (tn/m^3)';
            app.Db = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.PerdsEditFieldLabel = uilabel(app.CondicionesdeOperacinPanel);
            app.PerdsEditFieldLabel.Text = '% Perds';
            app.Pp = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.SolidosEditFieldLabel_2 = uilabel(app.CondicionesdeOperacinPanel);
            app.SolidosEditFieldLabel_2.Text = '%Solidos';
            app.Psol = uieditfield(app.CondicionesdeOperacinPanel, 'numeric');
            app.DescargaPanel = uipanel(app.UIFigure);
            app.DescargaPanel.Title = '                         Descarga';
            app.P80umEditFieldLabel = uilabel(app.DescargaPanel);
            app.P80umEditFieldLabel.Text = 'P80 (um)';
            app.P80 = uieditfield(app.DescargaPanel, 'numeric');
            app.Dnspultnm3EditFieldLabel = uilabel(app.DescargaPanel);
            app.Dnspultnm3EditFieldLabel.Text = 'Dns pul (tn/m^3)';
            app.Dpulpa = uieditfield(app.DescargaPanel, 'numeric');
            app.DatosOperacionalesPanel = uipanel(app.UIFigure);
            app.DatosOperacionalesPanel.Title = '               Datos Operacionales';
            app.WiokWhtnMEditFieldLabel = uilabel(app.DatosOperacionalesPanel);
            app.WiokWhtnMEditFieldLabel.Text = 'Wio (kWh/tnM)';
            app.Wio = uieditfield(app.DatosOperacionalesPanel, 'numeric');
            app.PnetakWEditFieldLabel = uilabel(app.DatosOperacionalesPanel);
            app.PnetakWEditFieldLabel.Text = 'P neta (kW)';
            app.Pno = uieditfield(app.DatosOperacionalesPanel, 'numeric');
            app.PtotalkWEditFieldLabel = uilabel(app.DatosOperacionalesPanel);
            app.PtotalkWEditFieldLabel.Text = 'P total (kW)';
            app.Pto = uieditfield(app.DatosOperacionalesPanel, 'numeric');
            app.EespkWhtnEditFieldLabel = uilabel(app.DatosOperacionalesPanel);
            app.EespkWhtnEditFieldLabel.Text = 'E esp  (kWh/tn)';
            app.Eeo = uieditfield(app.DatosOperacionalesPanel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '                             Panel';
            app.Calcular = uibutton(app.Panel, 'push');
            app.Calcular.ButtonPushedFcn = createCallbackFcn(app, @CalcularPushed, true);
            app.Calcular.Text = 'Calcular';
            app.Ayuda = uibutton(app.Panel, 'push');
            app.Ayuda.Text = 'Ayuda';
            app.DatossimuladosPanel = uipanel(app.UIFigure);
            app.DatossimuladosPanel.Title = '                    Datos simulados';
            app.EeskWhtnEditFieldLabel = uilabel(app.DatossimuladosPanel);
            app.EeskWhtnEditFieldLabel.Text = 'E es (kWh/tn)';
            app.Ees = uieditfield(app.DatossimuladosPanel, 'numeric');
            app.PnetakWEditFieldLabel_2 = uilabel(app.DatossimuladosPanel);
            app.PnetakWEditFieldLabel_2.Text = 'P neta (kW)';
            app.Pns = uieditfield(app.DatossimuladosPanel, 'numeric');
            app.DiametroftEditFieldLabel = uilabel(app.DatossimuladosPanel);
            app.DiametroftEditFieldLabel.Text = 'Diametro (ft)';
            app.Diamsim = uieditfield(app.DatossimuladosPanel, 'numeric');
            app.LongitufftEditFieldLabel = uilabel(app.DatossimuladosPanel);
            app.LongitufftEditFieldLabel.Text = 'Longituf (ft)';
            app.Longsim = uieditfield(app.DatossimuladosPanel, 'numeric');
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_prueba_axes

            % Associate the Simulink Model
            app.Simulation = simulation('untitled');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end